classdef App_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        RightPanel             matlab.ui.container.Panel
        TabGroup               matlab.ui.container.TabGroup
        TrajectoryTab          matlab.ui.container.Tab
        BROWSEIMAGEButton      matlab.ui.control.Button
        PROCESSIMAGEButton     matlab.ui.control.Button
        ROBOTButton            matlab.ui.control.Button
        TRAJECTORYButton       matlab.ui.control.Button
        TRYFILTERSButton       matlab.ui.control.Button
        Image2                 matlab.ui.control.Image
        Filtered_Image         matlab.ui.control.Image
        RAWIMAGELabel          matlab.ui.control.Label
        FILTEREDIMAGELabel     matlab.ui.control.Label
        Corrected_Image        matlab.ui.control.Image
        CORRECTEDIMAGELabel    matlab.ui.control.Label
        DRAWNLINELabel         matlab.ui.control.Label
        Drawn_Line_Im          matlab.ui.control.Image
        Found_Points           matlab.ui.control.Label
        U_maxmmEditFieldLabel  matlab.ui.control.Label
        U_max_numeric          matlab.ui.control.NumericEditField
        V_maxmmEditFieldLabel  matlab.ui.control.Label
        V_max_numeric          matlab.ui.control.NumericEditField
        PrecisionmmLabel       matlab.ui.control.Label
        Precision_numeric      matlab.ui.control.NumericEditField
        RobotTab               matlab.ui.container.Tab
        L1Slider               matlab.ui.control.Slider
        L1Label                matlab.ui.control.Label
        L2Slider               matlab.ui.control.Slider
        L3Slider               matlab.ui.control.Slider
        L2Label                matlab.ui.control.Label
        L3Label                matlab.ui.control.Label
        L4Slider               matlab.ui.control.Slider
        L5Slider               matlab.ui.control.Slider
        L4Label                matlab.ui.control.Label
        L5Label                matlab.ui.control.Label
        L1Value                matlab.ui.control.Label
        TRAJECTORYButton_2     matlab.ui.control.Button
        ROBOTButton_2          matlab.ui.control.Button
        SAVELENGHTSButton      matlab.ui.control.Button
        A1Slider               matlab.ui.control.Slider
        Label                  matlab.ui.control.Label
        A2Slider               matlab.ui.control.Slider
        A3Slider               matlab.ui.control.Slider
        Label_2                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        A4Slider               matlab.ui.control.Slider
        Label_4                matlab.ui.control.Label
        SAVEANGLESButton       matlab.ui.control.Button
        SHOWWORKSPACEButton    matlab.ui.control.Button
        LENGTHSLabel           matlab.ui.control.Label
        ANGLELIMITSLabel       matlab.ui.control.Label
        L2Value                matlab.ui.control.Label
        L3Value                matlab.ui.control.Label
        L4Value                matlab.ui.control.Label
        L5Value                matlab.ui.control.Label
        A1Value                matlab.ui.control.Label
        A2Value                matlab.ui.control.Label
        A3Value                matlab.ui.control.Label
        A4Value                matlab.ui.control.Label
        MOVEROBOTButton        matlab.ui.control.Button
        RESETLENGHTSButton     matlab.ui.control.Button
        RobotGraph             matlab.ui.container.Panel
        A5Slider               matlab.ui.control.Slider
        A5Label                matlab.ui.control.Label
        A5Value                matlab.ui.control.Label
        TryFiltersTab          matlab.ui.container.Tab
        BROWSEIMAGEButton_2    matlab.ui.control.Button
        SAVEANDRETURNButton    matlab.ui.control.Button
        Red_Mask_Im            matlab.ui.control.Image
        REDMASKLabel           matlab.ui.control.Label
        APPLYFILTERButton      matlab.ui.control.Button
        RSliderLabel           matlab.ui.control.Label
        RSlider_r_low          matlab.ui.control.Slider
        GSliderLabel           matlab.ui.control.Label
        RSlider_g_low          matlab.ui.control.Slider
        BSliderLabel           matlab.ui.control.Label
        RSlider_b_low          matlab.ui.control.Slider
        BSlider_2Label         matlab.ui.control.Label
        RSlider_b_high         matlab.ui.control.Slider
        GSlider_2Label         matlab.ui.control.Label
        RSlider_g_high         matlab.ui.control.Slider
        RSlider_2Label         matlab.ui.control.Label
        RSlider_r_high         matlab.ui.control.Slider
        LowLimitLabel          matlab.ui.control.Label
        HighLimitLabel         matlab.ui.control.Label
        RSlider_r_low_label    matlab.ui.control.Label
        RSlider_g_low_label    matlab.ui.control.Label
        RSlider_b_low_label    matlab.ui.control.Label
        RSlider_b_high_label   matlab.ui.control.Label
        RSlider_g_high_label   matlab.ui.control.Label
        RSlider_r_high_label   matlab.ui.control.Label
        GSlider_b_high_label   matlab.ui.control.Label
        GSlider_b_low_label    matlab.ui.control.Label
        GSlider_g_low_label    matlab.ui.control.Label
        GSlider_g_high_label   matlab.ui.control.Label
        GSlider_r_low_label    matlab.ui.control.Label
        GREENMASKLabel         matlab.ui.control.Label
        GSlider_r_high_label   matlab.ui.control.Label
        LowLimitLabel_2        matlab.ui.control.Label
        BSlider_4Label         matlab.ui.control.Label
        HighLimitLabel_2       matlab.ui.control.Label
        BSlider_3Label         matlab.ui.control.Label
        GSlider_b_high         matlab.ui.control.Slider
        GSlider_b_low          matlab.ui.control.Slider
        GSlider_4Label         matlab.ui.control.Label
        GSlider_g_low          matlab.ui.control.Slider
        RSlider_4Label         matlab.ui.control.Label
        GSlider_r_low          matlab.ui.control.Slider
        GSlider_3Label         matlab.ui.control.Label
        GSlider_g_high         matlab.ui.control.Slider
        Green_Mask_Im          matlab.ui.control.Image
        RSlider_3Label         matlab.ui.control.Label
        GSlider_r_high         matlab.ui.control.Slider
        Image3                 matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        is_image = false;
        path;               %Path to raw image
        u_real = 200;       %real u length of image in mm
        v_real = 150;       %real v length of image in mm
        precision = 0.1     %precision used for point distances in mm
        
        %Robot
        widowXMKII;
        table;
        robotFig;
        robotAxes;
        is_WS_shown = false;
        handle;
        thlim = [80 80 80 80 80];
        
        %Filtering properties
        red_mask_r_low = 99;
        red_mask_r_high = 185;
        red_mask_g_low = 10;
        red_mask_g_high = 113;
        red_mask_b_low = 10;
        red_mask_b_high = 113;
        
        green_mask_r_low = 10;
        green_mask_r_high = 100;
        green_mask_g_low = 10;
        green_mask_g_high = 150;
        green_mask_b_low = 10;
        green_mask_b_high = 150;
        
        %Filtering tab properties
        temp_red_mask_r_low = 99;
        temp_red_mask_r_high = 185;
        temp_red_mask_g_low = 10;
        temp_red_mask_g_high = 113;
        temp_red_mask_b_low = 10;
        temp_red_mask_b_high = 113;
        
        temp_green_mask_r_low = 10;
        temp_green_mask_r_high = 100;
        temp_green_mask_g_low = 10;
        temp_green_mask_g_high = 150;
        temp_green_mask_b_low = 10;
        temp_green_mask_b_high = 150;
        
        %Diferent images
        raw_im;
        red_mask;
        green_mask;
        combined_filtered_masks;
        processed_image;
        drawn_line;
        
        real_points;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app)
            %Creation robot
            X0 = 100;
            Y0 = 150;
            Z0 = 150;
            initPos = [X0, Y0, Z0];
            L1 = 130;
            L2 = 144;
            L3 = 53;
            L4 = 144;
            L5 = 144;
            Lee = 144;
            app.widowXMKII = Widow(L1,L2,L3,L4,L5,Lee,initPos);
            
            %Creation table
            width = 200;
            length = 150;
            color = [0 0 1];
            app.table = Table(X0,Y0,Z0,width,length,color);
        end

        % Button pushed function: BROWSEIMAGEButton, 
        % BROWSEIMAGEButton_2
        function browseImage(app, event)
            [file,path] = uigetfile({'*.*';'*.png';'*.jpg';'*.bmp'},'Select a trajectory file');
            if path ~= 0
                fullname = [path file];
                app.Image2.ImageSource = fullname;
                app.path = fullname;
                app.raw_im = iread(fullname);
                app.is_image = true;
            end
        end

        % Button pushed function: PROCESSIMAGEButton
        function processImage(app, event)
            if app.is_image == true
                
                %Pop-up Filtering
                app.PROCESSIMAGEButton.Text = "FILTERING...";
                fig = app.UIFigure;
                s = uiconfirm(fig,'Press OK to start Filtering','Info','Options',{'OK'},'DefaultOption',1);
                
                %Filtering proggres bar 
                filterProgress = uiprogressdlg(fig,'Title','Please Wait','Message','Filtering...','Indeterminate','on');
                drawnow
                svd(rand(5000));
            
                
                %Process image masks
                app.red_mask = create_mask(app.raw_im, app.red_mask_r_low, app.red_mask_r_high, app.red_mask_g_low, app.red_mask_g_high, app.red_mask_b_low, app.red_mask_b_high);
                app.green_mask = create_mask(app.raw_im, app.green_mask_r_low, app.green_mask_r_high, app.green_mask_g_low, app.green_mask_g_high, app.green_mask_b_low, app.green_mask_b_high);
                %Filter masks
                app.red_mask = filter_mask(app.red_mask);
                app.green_mask = filter_mask(app.green_mask);
                
                close(filterProgress)
                
                %Pop-up Processing
                app.PROCESSIMAGEButton.Text = "PROCESSING...";
                fig = app.UIFigure;
                s = uiconfirm(fig,'Press OK to start Processing','Info','Options',{'OK'},'DefaultOption',1);
               
                %Processing proggres bar 
                processProgress = uiprogressdlg(fig,'Title','Please Wait','Message','Processing...','Indeterminate','on');
                drawnow
                svd(rand(5000));
                
                %Combine filtered masks (just for showing)
                app.combined_filtered_masks = (app.red_mask + app.green_mask) ~= 0;
                app.Filtered_Image.ImageSource = cat(3, double(app.combined_filtered_masks), double(app.combined_filtered_masks), double(app.combined_filtered_masks));
                drawnow
                
                try           
                    %Obtain fully corrected image and drawn line
                    [app.processed_image, app.drawn_line] = get_corrected_images(app.raw_im, app.red_mask, app.green_mask, app.u_real, app.v_real, app.precision); 
                    
                    %Obtain points
                    app.real_points = get_real_points(app.drawn_line, app.precision);
                    
                    %Finished processing, show images
                    app.Drawn_Line_Im.ImageSource = cat(3, double(app.drawn_line), double(app.drawn_line), double(app.drawn_line));
                    app.Corrected_Image.ImageSource = double(app.processed_image);
                    
                    app.Found_Points.Text = sprintf(['Found points:\n\t' 'Point A: ( ' num2str(app.real_points(1,1)) '; ' num2str(app.real_points(2,1)) ')\n\t' 'Point B: ( ' num2str(app.real_points(1,2)) '; ' num2str(app.real_points(2,2)) ')']);
                
                catch error
                    %Close progress bar
                    close(processProgress)
                    %Get error
                    fig = app.UIFigure;
                    report = getReport(error,'basic');
                    tree = htmlTree(report);
                    str = extractHTMLText(tree);
                    
                    %Show error
                    msg = ['There was a problem processing the trajectory'...
                        'Maybe the filtered image is not correct. Please try differents filters' newline str];
                    uialert(fig,msg,'Error Message');
                end
               
                app.PROCESSIMAGEButton.Text = "PROCESS IMAGE";
                
            else
                fig = app.UIFigure;
                uialert(fig,'File not found','Invalid File');
            end

        end

        % Button pushed function: ROBOTButton
        function robotTab(app, event)
            app.TabGroup.SelectedTab = app.RobotTab;
            % Create external plot
            app.robotFig = figure("Visible","on");
            app.robotAxes = axes(app.robotFig,"XColor","w","YColor","w","ZColor","w","GridColor","black","ZLim",[-700 700]);
            hold on;
            app.table.drawTable();
            app.widowXMKII.drawRobot();
            app.handle = copyobj(app.robotAxes, app.RobotGraph);
            hold off;
        end

        % Button pushed function: TRYFILTERSButton
        function TRYFILTERSButtonPushed(app, event)
            if app.is_image == true
                app.TabGroup.SelectedTab = app.TryFiltersTab;
                %create initial masks
                app.red_mask = create_mask(app.raw_im, app.temp_red_mask_r_low, app.temp_red_mask_r_high, app.temp_red_mask_g_low, app.temp_red_mask_g_high, app.temp_red_mask_b_low, app.temp_red_mask_b_high);
                app.green_mask = create_mask(app.raw_im, app.temp_green_mask_r_low, app.temp_green_mask_r_high, app.temp_green_mask_g_low, app.temp_green_mask_g_high, app.temp_green_mask_b_low, app.temp_green_mask_b_high);
                %display masks
                app.Red_Mask_Im.ImageSource = cat(3, double(app.red_mask), double(app.red_mask), double(app.red_mask));
                app.Green_Mask_Im.ImageSource = cat(3, double(app.green_mask), double(app.green_mask), double(app.green_mask));
                
                %set initial values for sliders and labels
                app.RSlider_r_low.Value = app.temp_red_mask_r_low;
                app.RSlider_r_low_label.Text = num2str(app.temp_red_mask_r_low);
                app.RSlider_g_low.Value = app.temp_red_mask_g_low;
                app.RSlider_g_low_label.Text = num2str(app.temp_red_mask_g_low);
                app.RSlider_b_low.Value = app.temp_red_mask_b_low;
                app.RSlider_b_low_label.Text = num2str(app.temp_red_mask_b_low);
                
                app.RSlider_r_high.Value = app.temp_red_mask_r_high;
                app.RSlider_b_high_label.Text = num2str(app.temp_red_mask_r_high);
                app.RSlider_g_high.Value = app.temp_red_mask_g_high;
                app.RSlider_g_high_label.Text = num2str(app.temp_red_mask_g_high);
                app.RSlider_b_high.Value = app.temp_red_mask_b_high;
                app.RSlider_r_high_label.Text = num2str(app.temp_red_mask_b_high);
                
                app.GSlider_r_low.Value = app.temp_green_mask_r_low;
                app.GSlider_r_low_label.Text = num2str(app.temp_green_mask_r_low);
                app.GSlider_g_low.Value = app.temp_green_mask_g_low;
                app.GSlider_g_low_label.Text = num2str(app.temp_green_mask_g_low);
                app.GSlider_b_low.Value = app.temp_green_mask_b_low;
                app.GSlider_b_low_label.Text = num2str(app.temp_green_mask_b_low);
                
                app.GSlider_r_high.Value = app.temp_green_mask_r_high;
                app.GSlider_b_high_label.Text = num2str(app.temp_green_mask_r_high);
                app.GSlider_g_high.Value = app.temp_green_mask_g_high;
                app.GSlider_g_high_label.Text = num2str(app.temp_green_mask_g_high);
                app.GSlider_b_high.Value = app.temp_green_mask_b_high;
                app.GSlider_r_high_label.Text = num2str(app.temp_green_mask_b_high);
                
            else
                fig = app.UIFigure;
                uialert(fig,'File not found','Invalid File');
            end 
        end

        % Value changed function: Precision_numeric, U_max_numeric, 
        % V_max_numeric
        function V_max_numericValueChanged(app, event)
            app.u_real = app.U_max_numeric.Value;
            app.v_real = app.V_max_numeric.Value;
            app.precision = app.Precision_numeric.Value;
        end

        % Value changing function: L1Slider
        function valueL1change(app, event)
            changingValue = round(event.Value);
            app.L1Value.Text = num2str(changingValue);
        end

        % Value changing function: L2Slider
        function valueL2change(app, event)
            changingValue = round(event.Value);
            app.L2Value.Text = num2str(changingValue);
        end

        % Value changing function: L3Slider
        function valueL3change(app, event)
            changingValue = round(event.Value);
            app.L3Value.Text = num2str(changingValue);
        end

        % Value changing function: L4Slider
        function valueL4change(app, event)
            changingValue = round(event.Value);
            app.L4Value.Text = num2str(changingValue);
        end

        % Value changing function: L5Slider
        function valueL5change(app, event)
            changingValue = round(event.Value);
            app.L5Value.Text = num2str(changingValue);
        end

        % Button pushed function: TRAJECTORYButton_2
        function trajectoryTab(app, event)
            app.TabGroup.SelectedTab = app.TrajectoryTab;
        end

        % Button pushed function: SAVELENGHTSButton
        function saveLengths(app, event)
            %Drawing proggres bar 
            fig = app.UIFigure;
            drawProgress = uiprogressdlg(fig,'Title','Please Wait','Message','Constructing robot...','Indeterminate','on');
            drawnow
            svd(rand(5000));
            %Save leghts
            L1 = round(app.L1Slider.Value);
            L2 = round(app.L2Slider.Value);
            L3 = round(app.L3Slider.Value);
            L4 = round(app.L4Slider.Value);
            L5 = round(app.L5Slider.Value);
            Lee = 144;
            %Changing robot lenghts
            app.widowXMKII = Widow(L1,L2,L3,L4,L5,Lee,app.widowXMKII.currentPos);
            %Move robot to initial position
            app.robotFig = figure("Visible","on");
            app.robotAxes = axes(app.robotFig,"XColor","w","YColor","w","ZColor","w","GridColor","black","ZLim",[-700 700]);
            hold on;
            app.table.drawTable();
            app.widowXMKII.drawRobot();
            delete(app.handle)
            hold off;
            app.handle = copyobj(app.robotAxes, app.RobotGraph);
            close(drawProgress);
        end

        % Value changing function: A1Slider
        function valueA1change(app, event)
            changingValue = round(event.Value);
            app.A1Value.Text = strcat(num2str(changingValue),'°');
        end

        % Value changing function: A2Slider
        function valueA2change(app, event)
            changingValue = round(event.Value);
            app.A2Value.Text = strcat(num2str(changingValue),'°');
        end

        % Value changing function: A3Slider
        function valueA3change(app, event)
            changingValue = round(event.Value);
            app.A3Value.Text = strcat(num2str(changingValue),'°');
        end

        % Value changing function: A4Slider
        function valueA4change(app, event)
            changingValue = round(event.Value);
            app.A4Value.Text = strcat(num2str(changingValue),'°');
        end

        % Button pushed function: SAVEANGLESButton
        function saveAngles(app, event)
            th1 = round(app.A1Slider.Value);
            th2 = round(app.A2Slider.Value);
            th3 = round(app.A3Slider.Value);
            th4 = round(app.A4Slider.Value);
            th5 = round(app.A5Slider.Value);
            app.thlim = [th1, th2, th3, th4, th5];
        end

        % Button pushed function: SHOWWORKSPACEButton
        function showWorkspace(app, event)
            %Workspace progress bar 
            fig = app.UIFigure;
            WSProgress = uiprogressdlg(fig,'Title','Please Wait','Message','Calculating workspace...','Indeterminate','on');
            drawnow
            svd(rand(5000));
            app.robotFig = figure("Visible","on");
            app.robotAxes = axes(app.robotFig,"XColor","w","YColor","w","ZColor","w","GridColor","black","ZLim",[-700 700]);
            app.widowXMKII.drawRobot()
            app.widowXMKII.drawWorkspace(app.thlim);
            delete(app.handle)
            app.handle = copyobj(app.robotAxes, app.RobotGraph);
            close(WSProgress);
        end

        % Button pushed function: MOVEROBOTButton
        function moveRobot(app, event)
            X0 = 100;
            Y0 = 100;
            Z0 = 150;
            app.robotFig = figure("Visible","on");
            app.robotAxes = axes(app.robotFig,"XColor","w","YColor","w","ZColor","w","GridColor","black","ZLim",[-700 700]);
            app.widowXMKII.drawLine([X0+app.real_points(1,1) Y0+app.real_points(2,1) Z0], [X0+app.real(1,2) Y0+app.real(2,2) Z0], 10);
            delete(app.handle)
            app.handle = copyobj(app.robotAxes, app.RobotGraph);            
        end

        % Button pushed function: RESETLENGHTSButton
        function resetLengths(app, event)
            L1 = 130;
            L2 = 144;
            L3 = 53;
            L4 = 144;
            L5 = 144;
            app.L1Value.Text = num2str(L1);
            app.L2Value.Text = num2str(L2);
            app.L3Value.Text = num2str(L3);
            app.L4Value.Text = num2str(L4);
            app.L5Value.Text = num2str(L5);
            app.L1Slider.Value = L1;
            app.L2Slider.Value = L2;
            app.L3Slider.Value = L3;
            app.L4Slider.Value = L4;
            app.L5Slider.Value = L5;
        end

        % Value changing function: A5Slider
        function valueA5change(app, event)
            changingValue = round(event.Value);
            app.A5Value.Text = strcat(num2str(changingValue),'°');
        end

        % Button pushed function: SAVEANDRETURNButton
        function SAVEANDRETURNButtonPushed(app, event)
            app.red_mask_r_low      = app.temp_red_mask_r_low;
            app.red_mask_r_high     = app.temp_red_mask_r_high;
            app.red_mask_g_low      = app.temp_red_mask_g_low;
            app.red_mask_g_high     = app.temp_red_mask_g_high;
            app.red_mask_b_low      = app.temp_red_mask_b_low;
            app.red_mask_b_high     = app.temp_red_mask_b_high;
            
            app.green_mask_r_low    = app.temp_green_mask_r_low;
            app.green_mask_r_high   = app.temp_green_mask_r_high;
            app.green_mask_g_low    = app.temp_green_mask_g_low;
            app.green_mask_g_high   = app.temp_green_mask_g_high;
            app.green_mask_b_low    = app.temp_green_mask_b_low;
            app.green_mask_b_high   = app.temp_green_mask_b_high;
            
            app.TabGroup.SelectedTab = app.TrajectoryTab;
        end

        % Button pushed function: APPLYFILTERButton
        function APPLYFILTERButtonPushed(app, event)
            %Pop-up Filtering
            app.APPLYFILTERButton.Text = "FILTERING...";
            fig = app.UIFigure;
            s = uiconfirm(fig,'Press OK to start Filtering','Info','Options',{'OK'},'DefaultOption',1);
            
            %Filtering proggres bar 
            filterProgress = uiprogressdlg(fig,'Title','Please Wait','Message','Filtering...','Indeterminate','on');
            drawnow
            svd(rand(5000));
            
            app.red_mask = filter_mask(app.red_mask);
            app.green_mask = filter_mask(app.green_mask);
            
            app.APPLYFILTERButton.Text = "APPLY FILTER";
            
            app.Red_Mask_Im.ImageSource = cat(3, double(app.red_mask), double(app.red_mask), double(app.red_mask));
            app.Green_Mask_Im.ImageSource = cat(3, double(app.green_mask), double(app.green_mask), double(app.green_mask));
            
            close(filterProgress)
        end

        % Value changed function: RSlider_b_high, RSlider_b_low, 
        % RSlider_g_high, RSlider_g_low, RSlider_r_high, 
        % RSlider_r_low
        function RSlider_r_lowValueChanged(app, event)
            app.temp_red_mask_r_low = round(app.RSlider_r_low.Value);
            app.temp_red_mask_r_high = round(app.RSlider_r_high.Value);
            app.temp_red_mask_g_low = round(app.RSlider_g_low.Value);
            app.temp_red_mask_g_high = round(app.RSlider_g_high.Value);
            app.temp_red_mask_b_low = round(app.RSlider_b_low.Value);
            app.temp_red_mask_b_high = round(app.RSlider_b_high.Value);
            
            
            app.red_mask = create_mask(app.raw_im, app.temp_red_mask_r_low, app.temp_red_mask_r_high, app.temp_red_mask_g_low, app.temp_red_mask_g_high, app.temp_red_mask_b_low, app.temp_red_mask_b_high);
            
            app.RSlider_r_low_label.Text = num2str(app.temp_red_mask_r_low);
            app.RSlider_g_low_label.Text = num2str(app.temp_red_mask_g_low);
            app.RSlider_b_low_label.Text = num2str(app.temp_red_mask_b_low);
            app.RSlider_r_high_label.Text = num2str(app.temp_red_mask_r_high);
            app.RSlider_g_high_label.Text = num2str(app.temp_red_mask_g_high);
            app.RSlider_b_high_label.Text = num2str(app.temp_red_mask_b_high);
            
            app.Red_Mask_Im.ImageSource = cat(3, double(app.red_mask), double(app.red_mask), double(app.red_mask));
        end

        % Value changed function: GSlider_b_high, GSlider_b_low, 
        % GSlider_g_high, GSlider_g_low, GSlider_r_high, 
        % GSlider_r_low
        function GSlider_r_lowValueChanged(app, event)
            app.temp_green_mask_r_low = round(app.GSlider_r_low.Value);
            app.temp_green_mask_r_high = round(app.GSlider_r_high.Value);
            app.temp_green_mask_g_low = round(app.GSlider_g_low.Value);
            app.temp_green_mask_g_high = round(app.GSlider_g_high.Value);
            app.temp_green_mask_b_low = round(app.GSlider_b_low.Value);
            app.temp_green_mask_b_high = round(app.GSlider_b_high.Value);
            
            
            app.green_mask = create_mask(app.raw_im, app.temp_green_mask_r_low, app.temp_green_mask_r_high, app.temp_green_mask_g_low, app.temp_green_mask_g_high, app.temp_green_mask_b_low, app.temp_green_mask_b_high);
            
            app.GSlider_r_low_label.Text = num2str(app.temp_green_mask_r_low);
            app.GSlider_g_low_label.Text = num2str(app.temp_green_mask_g_low);
            app.GSlider_b_low_label.Text = num2str(app.temp_green_mask_b_low);
            app.GSlider_r_high_label.Text = num2str(app.temp_green_mask_r_high);
            app.GSlider_g_high_label.Text = num2str(app.temp_green_mask_g_high);
            app.GSlider_b_high_label.Text = num2str(app.temp_green_mask_b_high);
            
            app.Green_Mask_Im.ImageSource = cat(3, double(app.green_mask), double(app.green_mask), double(app.green_mask));
        end

        % Image clicked function: Image3
        function exitTryFilters(app, event)
            app.TabGroup.SelectedTab = app.TrajectoryTab;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1080, 1080};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.Tooltip = {''};
            app.TrajectoryTab = uitab(app.TabGroup);
            app.TrajectoryTab.Title = 'Trajectory';
            app.BROWSEIMAGEButton = uibutton(app.TrajectoryTab, 'push');
            app.BROWSEIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @browseImage, true);
            app.BROWSEIMAGEButton.Text = 'BROWSE IMAGE';
            app.PROCESSIMAGEButton = uibutton(app.TrajectoryTab, 'push');
            app.PROCESSIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @processImage, true);
            app.PROCESSIMAGEButton.Text = 'PROCESS IMAGE';
            app.ROBOTButton = uibutton(app.TrajectoryTab, 'push');
            app.ROBOTButton.ButtonPushedFcn = createCallbackFcn(app, @robotTab, true);
            app.ROBOTButton.Text = 'ROBOT';
            app.TRAJECTORYButton = uibutton(app.TrajectoryTab, 'push');
            app.TRAJECTORYButton.Text = 'TRAJECTORY';
            app.TRYFILTERSButton = uibutton(app.TrajectoryTab, 'push');
            app.TRYFILTERSButton.ButtonPushedFcn = createCallbackFcn(app, @TRYFILTERSButtonPushed, true);
            app.TRYFILTERSButton.Text = 'TRY FILTERS';
            app.Image2 = uiimage(app.TrajectoryTab);
            app.Image2.ImageSource = 'Image.svg';
            app.Filtered_Image = uiimage(app.TrajectoryTab);
            app.Filtered_Image.ImageSource = 'Image.svg';
            app.RAWIMAGELabel = uilabel(app.TrajectoryTab);
            app.RAWIMAGELabel.Text = 'RAW IMAGE';
            app.FILTEREDIMAGELabel = uilabel(app.TrajectoryTab);
            app.FILTEREDIMAGELabel.Text = 'FILTERED IMAGE';
            app.Corrected_Image = uiimage(app.TrajectoryTab);
            app.Corrected_Image.ImageSource = 'Image.svg';
            app.CORRECTEDIMAGELabel = uilabel(app.TrajectoryTab);
            app.CORRECTEDIMAGELabel.Text = 'CORRECTED IMAGE';
            app.DRAWNLINELabel = uilabel(app.TrajectoryTab);
            app.DRAWNLINELabel.Text = {'DRAWN LINE'; ''};
            app.Drawn_Line_Im = uiimage(app.TrajectoryTab);
            app.Drawn_Line_Im.ImageSource = 'Image.svg';
            app.Found_Points = uilabel(app.TrajectoryTab);
            app.Found_Points.Text = '';
            app.U_maxmmEditFieldLabel = uilabel(app.TrajectoryTab);
            app.U_maxmmEditFieldLabel.Text = 'U_max [mm]';
            app.U_max_numeric = uieditfield(app.TrajectoryTab, 'numeric');
            app.U_max_numeric.Limits = [0 Inf];
            app.U_max_numeric.ValueChangedFcn = createCallbackFcn(app, @V_max_numericValueChanged, true);
            app.U_max_numeric.Tooltip = {'Total length of U axis in mm.'};
            app.U_max_numeric.Value = 200;
            app.V_maxmmEditFieldLabel = uilabel(app.TrajectoryTab);
            app.V_maxmmEditFieldLabel.Text = 'V_max [mm]';
            app.V_max_numeric = uieditfield(app.TrajectoryTab, 'numeric');
            app.V_max_numeric.Limits = [0 Inf];
            app.V_max_numeric.ValueChangedFcn = createCallbackFcn(app, @V_max_numericValueChanged, true);
            app.V_max_numeric.Tooltip = {'Total length of V axis in mm.'};
            app.V_max_numeric.Value = 150;
            app.PrecisionmmLabel = uilabel(app.TrajectoryTab);
            app.PrecisionmmLabel.Text = 'Precision [mm]';
            app.Precision_numeric = uieditfield(app.TrajectoryTab, 'numeric');
            app.Precision_numeric.Limits = [0 1];
            app.Precision_numeric.ValueChangedFcn = createCallbackFcn(app, @V_max_numericValueChanged, true);
            app.Precision_numeric.Tooltip = {'Presicion used in obtaining the target points. Smaller presicion inquires longer processing times.'};
            app.Precision_numeric.Value = 0.1;
            app.RobotTab = uitab(app.TabGroup);
            app.RobotTab.Title = 'Robot';
            app.L1Slider = uislider(app.RobotTab);
            app.L1Slider.Limits = [117 143];
            app.L1Slider.MajorTicks = [];
            app.L1Slider.ValueChangingFcn = createCallbackFcn(app, @valueL1change, true);
            app.L1Slider.MinorTicks = [];
            app.L1Slider.Value = 130;
            app.L1Label = uilabel(app.RobotTab);
            app.L1Label.Text = 'L1';
            app.L2Slider = uislider(app.RobotTab);
            app.L2Slider.Limits = [130 158];
            app.L2Slider.MajorTicks = [];
            app.L2Slider.ValueChangingFcn = createCallbackFcn(app, @valueL2change, true);
            app.L2Slider.MinorTicks = [];
            app.L2Slider.Value = 144;
            app.L3Slider = uislider(app.RobotTab);
            app.L3Slider.Limits = [48 58];
            app.L3Slider.MajorTicks = [];
            app.L3Slider.ValueChangingFcn = createCallbackFcn(app, @valueL3change, true);
            app.L3Slider.MinorTicks = [];
            app.L3Slider.Value = 53;
            app.L2Label = uilabel(app.RobotTab);
            app.L2Label.Text = 'L2';
            app.L3Label = uilabel(app.RobotTab);
            app.L3Label.Text = 'L3';
            app.L4Slider = uislider(app.RobotTab);
            app.L4Slider.Limits = [130 158];
            app.L4Slider.MajorTicks = [130 134 138 142 146 150 154 158];
            app.L4Slider.ValueChangingFcn = createCallbackFcn(app, @valueL4change, true);
            app.L4Slider.MinorTicks = [];
            app.L4Slider.Value = 144;
            app.L5Slider = uislider(app.RobotTab);
            app.L5Slider.Limits = [130 158];
            app.L5Slider.MajorTicks = [];
            app.L5Slider.ValueChangingFcn = createCallbackFcn(app, @valueL5change, true);
            app.L5Slider.MinorTicks = [];
            app.L5Slider.Value = 144;
            app.L4Label = uilabel(app.RobotTab);
            app.L4Label.Text = 'L4';
            app.L5Label = uilabel(app.RobotTab);
            app.L5Label.Text = 'L5';
            app.L1Value = uilabel(app.RobotTab);
            app.L1Value.Text = '130';
            app.TRAJECTORYButton_2 = uibutton(app.RobotTab, 'push');
            app.TRAJECTORYButton_2.ButtonPushedFcn = createCallbackFcn(app, @trajectoryTab, true);
            app.TRAJECTORYButton_2.Text = 'TRAJECTORY';
            app.ROBOTButton_2 = uibutton(app.RobotTab, 'push');
            app.ROBOTButton_2.Text = 'ROBOT';
            app.SAVELENGHTSButton = uibutton(app.RobotTab, 'push');
            app.SAVELENGHTSButton.ButtonPushedFcn = createCallbackFcn(app, @saveLengths, true);
            app.SAVELENGHTSButton.Text = 'SAVE LENGHTS';
            app.A1Slider = uislider(app.RobotTab);
            app.A1Slider.MajorTicks = [];
            app.A1Slider.ValueChangingFcn = createCallbackFcn(app, @valueA1change, true);
            app.A1Slider.MinorTicks = [];
            app.A1Slider.Value = 80;
            app.Label = uilabel(app.RobotTab);
            app.Label.Text = 'θ1';
            app.A2Slider = uislider(app.RobotTab);
            app.A2Slider.MajorTicks = [];
            app.A2Slider.ValueChangingFcn = createCallbackFcn(app, @valueA2change, true);
            app.A2Slider.MinorTicks = [];
            app.A2Slider.Value = 80;
            app.A3Slider = uislider(app.RobotTab);
            app.A3Slider.MajorTicks = [];
            app.A3Slider.ValueChangingFcn = createCallbackFcn(app, @valueA3change, true);
            app.A3Slider.MinorTicks = [];
            app.A3Slider.Value = 80;
            app.Label_2 = uilabel(app.RobotTab);
            app.Label_2.Text = 'θ2';
            app.Label_3 = uilabel(app.RobotTab);
            app.Label_3.Text = 'θ3';
            app.A4Slider = uislider(app.RobotTab);
            app.A4Slider.MajorTicks = [];
            app.A4Slider.ValueChangingFcn = createCallbackFcn(app, @valueA4change, true);
            app.A4Slider.MinorTicks = [];
            app.A4Slider.Value = 80;
            app.Label_4 = uilabel(app.RobotTab);
            app.Label_4.Text = 'θ4';
            app.SAVEANGLESButton = uibutton(app.RobotTab, 'push');
            app.SAVEANGLESButton.ButtonPushedFcn = createCallbackFcn(app, @saveAngles, true);
            app.SAVEANGLESButton.Text = 'SAVE ANGLES';
            app.SHOWWORKSPACEButton = uibutton(app.RobotTab, 'push');
            app.SHOWWORKSPACEButton.ButtonPushedFcn = createCallbackFcn(app, @showWorkspace, true);
            app.SHOWWORKSPACEButton.Text = 'SHOW WORKSPACE';
            app.LENGTHSLabel = uilabel(app.RobotTab);
            app.LENGTHSLabel.Text = 'LENGTHS';
            app.ANGLELIMITSLabel = uilabel(app.RobotTab);
            app.ANGLELIMITSLabel.Text = 'ANGLE LIMITS';
            app.L2Value = uilabel(app.RobotTab);
            app.L2Value.Text = '144';
            app.L3Value = uilabel(app.RobotTab);
            app.L3Value.Text = '53';
            app.L4Value = uilabel(app.RobotTab);
            app.L4Value.Text = '144';
            app.L5Value = uilabel(app.RobotTab);
            app.L5Value.Text = '144';
            app.A1Value = uilabel(app.RobotTab);
            app.A1Value.Text = '80°';
            app.A2Value = uilabel(app.RobotTab);
            app.A2Value.Text = '80°';
            app.A3Value = uilabel(app.RobotTab);
            app.A3Value.Text = '80°';
            app.A4Value = uilabel(app.RobotTab);
            app.A4Value.Text = '80°';
            app.MOVEROBOTButton = uibutton(app.RobotTab, 'push');
            app.MOVEROBOTButton.ButtonPushedFcn = createCallbackFcn(app, @moveRobot, true);
            app.MOVEROBOTButton.Text = 'MOVE ROBOT';
            app.RESETLENGHTSButton = uibutton(app.RobotTab, 'push');
            app.RESETLENGHTSButton.ButtonPushedFcn = createCallbackFcn(app, @resetLengths, true);
            app.RESETLENGHTSButton.Icon = 'Reset.svg';
            app.RESETLENGHTSButton.Text = '';
            app.RobotGraph = uipanel(app.RobotTab);
            app.A5Slider = uislider(app.RobotTab);
            app.A5Slider.MajorTicks = [];
            app.A5Slider.ValueChangingFcn = createCallbackFcn(app, @valueA5change, true);
            app.A5Slider.MinorTicks = [];
            app.A5Slider.Value = 80;
            app.A5Label = uilabel(app.RobotTab);
            app.A5Label.Text = 'θ5';
            app.A5Value = uilabel(app.RobotTab);
            app.A5Value.Text = '80°';
            app.TryFiltersTab = uitab(app.TabGroup);
            app.TryFiltersTab.Title = 'Try Filters';
            app.BROWSEIMAGEButton_2 = uibutton(app.TryFiltersTab, 'push');
            app.BROWSEIMAGEButton_2.ButtonPushedFcn = createCallbackFcn(app, @browseImage, true);
            app.BROWSEIMAGEButton_2.Text = 'BROWSE IMAGE';
            app.SAVEANDRETURNButton = uibutton(app.TryFiltersTab, 'push');
            app.SAVEANDRETURNButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEANDRETURNButtonPushed, true);
            app.SAVEANDRETURNButton.Text = 'SAVE AND RETURN';
            app.Red_Mask_Im = uiimage(app.TryFiltersTab);
            app.Red_Mask_Im.ImageSource = 'Image.svg';
            app.REDMASKLabel = uilabel(app.TryFiltersTab);
            app.REDMASKLabel.Text = 'RED MASK';
            app.APPLYFILTERButton = uibutton(app.TryFiltersTab, 'push');
            app.APPLYFILTERButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYFILTERButtonPushed, true);
            app.APPLYFILTERButton.Text = 'APPLY FILTER';
            app.RSliderLabel = uilabel(app.TryFiltersTab);
            app.RSliderLabel.Text = 'R';
            app.RSlider_r_low = uislider(app.TryFiltersTab);
            app.RSlider_r_low.Limits = [0 255];
            app.RSlider_r_low.MajorTicks = [0 50 100 150 200 250];
            app.RSlider_r_low.ValueChangedFcn = createCallbackFcn(app, @RSlider_r_lowValueChanged, true);
            app.GSliderLabel = uilabel(app.TryFiltersTab);
            app.GSliderLabel.Text = 'G';
            app.RSlider_g_low = uislider(app.TryFiltersTab);
            app.RSlider_g_low.Limits = [0 255];
            app.RSlider_g_low.MajorTicks = [0 50 100 150 200 250];
            app.RSlider_g_low.ValueChangedFcn = createCallbackFcn(app, @RSlider_r_lowValueChanged, true);
            app.BSliderLabel = uilabel(app.TryFiltersTab);
            app.BSliderLabel.Text = 'B';
            app.RSlider_b_low = uislider(app.TryFiltersTab);
            app.RSlider_b_low.Limits = [0 255];
            app.RSlider_b_low.MajorTicks = [0 50 100 150 200 250];
            app.RSlider_b_low.ValueChangedFcn = createCallbackFcn(app, @RSlider_r_lowValueChanged, true);
            app.BSlider_2Label = uilabel(app.TryFiltersTab);
            app.BSlider_2Label.Text = 'B';
            app.RSlider_b_high = uislider(app.TryFiltersTab);
            app.RSlider_b_high.Limits = [0 255];
            app.RSlider_b_high.MajorTicks = [0 50 100 150 200 250];
            app.RSlider_b_high.ValueChangedFcn = createCallbackFcn(app, @RSlider_r_lowValueChanged, true);
            app.GSlider_2Label = uilabel(app.TryFiltersTab);
            app.GSlider_2Label.Text = 'G';
            app.RSlider_g_high = uislider(app.TryFiltersTab);
            app.RSlider_g_high.Limits = [0 255];
            app.RSlider_g_high.MajorTicks = [0 50 100 150 200 250];
            app.RSlider_g_high.ValueChangedFcn = createCallbackFcn(app, @RSlider_r_lowValueChanged, true);
            app.RSlider_2Label = uilabel(app.TryFiltersTab);
            app.RSlider_2Label.Text = 'R';
            app.RSlider_r_high = uislider(app.TryFiltersTab);
            app.RSlider_r_high.Limits = [0 255];
            app.RSlider_r_high.MajorTicks = [0 50 100 150 200 250];
            app.RSlider_r_high.ValueChangedFcn = createCallbackFcn(app, @RSlider_r_lowValueChanged, true);
            app.LowLimitLabel = uilabel(app.TryFiltersTab);
            app.LowLimitLabel.Text = 'Low Limit';
            app.HighLimitLabel = uilabel(app.TryFiltersTab);
            app.HighLimitLabel.Text = 'High Limit';
            app.RSlider_r_low_label = uilabel(app.TryFiltersTab);
            app.RSlider_r_low_label.Text = '';
            app.RSlider_g_low_label = uilabel(app.TryFiltersTab);
            app.RSlider_g_low_label.Text = '';
            app.RSlider_b_low_label = uilabel(app.TryFiltersTab);
            app.RSlider_b_low_label.Text = '';
            app.RSlider_b_high_label = uilabel(app.TryFiltersTab);
            app.RSlider_b_high_label.Text = '';
            app.RSlider_g_high_label = uilabel(app.TryFiltersTab);
            app.RSlider_g_high_label.Text = '';
            app.RSlider_r_high_label = uilabel(app.TryFiltersTab);
            app.RSlider_r_high_label.Text = '';
            app.GSlider_b_high_label = uilabel(app.TryFiltersTab);
            app.GSlider_b_high_label.Text = '';
            app.GSlider_b_low_label = uilabel(app.TryFiltersTab);
            app.GSlider_b_low_label.Text = '';
            app.GSlider_g_low_label = uilabel(app.TryFiltersTab);
            app.GSlider_g_low_label.Text = '';
            app.GSlider_g_high_label = uilabel(app.TryFiltersTab);
            app.GSlider_g_high_label.Text = '';
            app.GSlider_r_low_label = uilabel(app.TryFiltersTab);
            app.GSlider_r_low_label.Text = '';
            app.GREENMASKLabel = uilabel(app.TryFiltersTab);
            app.GREENMASKLabel.Text = 'GREEN MASK';
            app.GSlider_r_high_label = uilabel(app.TryFiltersTab);
            app.GSlider_r_high_label.Text = '';
            app.LowLimitLabel_2 = uilabel(app.TryFiltersTab);
            app.LowLimitLabel_2.Text = 'Low Limit';
            app.BSlider_4Label = uilabel(app.TryFiltersTab);
            app.BSlider_4Label.Text = 'B';
            app.HighLimitLabel_2 = uilabel(app.TryFiltersTab);
            app.HighLimitLabel_2.Text = 'High Limit';
            app.BSlider_3Label = uilabel(app.TryFiltersTab);
            app.BSlider_3Label.Text = 'B';
            app.GSlider_b_high = uislider(app.TryFiltersTab);
            app.GSlider_b_high.Limits = [0 255];
            app.GSlider_b_high.MajorTicks = [0 50 100 150 200 250];
            app.GSlider_b_high.ValueChangedFcn = createCallbackFcn(app, @GSlider_r_lowValueChanged, true);
            app.GSlider_b_low = uislider(app.TryFiltersTab);
            app.GSlider_b_low.Limits = [0 255];
            app.GSlider_b_low.MajorTicks = [0 50 100 150 200 250];
            app.GSlider_b_low.ValueChangedFcn = createCallbackFcn(app, @GSlider_r_lowValueChanged, true);
            app.GSlider_4Label = uilabel(app.TryFiltersTab);
            app.GSlider_4Label.Text = 'G';
            app.GSlider_g_low = uislider(app.TryFiltersTab);
            app.GSlider_g_low.Limits = [0 255];
            app.GSlider_g_low.MajorTicks = [0 50 100 150 200 250];
            app.GSlider_g_low.ValueChangedFcn = createCallbackFcn(app, @GSlider_r_lowValueChanged, true);
            app.RSlider_4Label = uilabel(app.TryFiltersTab);
            app.RSlider_4Label.Text = 'R';
            app.GSlider_r_low = uislider(app.TryFiltersTab);
            app.GSlider_r_low.Limits = [0 255];
            app.GSlider_r_low.MajorTicks = [0 50 100 150 200 250];
            app.GSlider_r_low.ValueChangedFcn = createCallbackFcn(app, @GSlider_r_lowValueChanged, true);
            app.GSlider_3Label = uilabel(app.TryFiltersTab);
            app.GSlider_3Label.Text = 'G';
            app.GSlider_g_high = uislider(app.TryFiltersTab);
            app.GSlider_g_high.Limits = [0 255];
            app.GSlider_g_high.MajorTicks = [0 50 100 150 200 250];
            app.GSlider_g_high.ValueChangedFcn = createCallbackFcn(app, @GSlider_r_lowValueChanged, true);
            app.Green_Mask_Im = uiimage(app.TryFiltersTab);
            app.Green_Mask_Im.ImageSource = 'Image.svg';
            app.RSlider_3Label = uilabel(app.TryFiltersTab);
            app.RSlider_3Label.Text = 'R';
            app.GSlider_r_high = uislider(app.TryFiltersTab);
            app.GSlider_r_high.Limits = [0 255];
            app.GSlider_r_high.MajorTicks = [0 50 100 150 200 250];
            app.GSlider_r_high.ValueChangedFcn = createCallbackFcn(app, @GSlider_r_lowValueChanged, true);
            app.Image3 = uiimage(app.TryFiltersTab);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @exitTryFilters, true);
            app.Image3.ImageSource = 'Cross.svg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end